classdef RX150 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        WaistServo1Label_2      matlab.ui.control.Label
        WaistServoKnob          matlab.ui.control.Knob
        ShoulderServo2Label     matlab.ui.control.Label
        ShoulderServoKnob       matlab.ui.control.Knob
        ElbowServo3Label        matlab.ui.control.Label
        ElbowServoKnob          matlab.ui.control.Knob
        CenterPanel             matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        StatusTextAreaLabel     matlab.ui.control.Label
        StatusTextArea          matlab.ui.control.TextArea
        MoveButton              matlab.ui.control.Button
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        ZEditField              matlab.ui.control.NumericEditField
        alphaEditFieldLabel     matlab.ui.control.Label
        alphaEditField          matlab.ui.control.NumericEditField
        betaEditFieldLabel      matlab.ui.control.Label
        betaEditField           matlab.ui.control.NumericEditField
        gammaEditFieldLabel     matlab.ui.control.Label
        gammaEditField          matlab.ui.control.NumericEditField
        DisplayModelButton      matlab.ui.control.Button
        HomeButton              matlab.ui.control.Button
        ConnectionLampLabel     matlab.ui.control.Label
        ConnectionLamp          matlab.ui.control.Lamp
        RightPanel              matlab.ui.container.Panel
        WristAngleServo4Label   matlab.ui.control.Label
        WristAngleServoKnob     matlab.ui.control.Knob
        WristRotateServo5Label  matlab.ui.control.Label
        WristRotateServoKnob    matlab.ui.control.Knob
        GripperServo6Label      matlab.ui.control.Label
        GripperServoKnob        matlab.ui.control.Knob
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Member Variables
    properties (Access = private)
        didLoadLib;
        didOpenPort;
        didSetBaudRate;
        didLoadModel;
        lib_name = 'dxl_x64_c';     % Dynamixel library name
        urdf_path = 'C:\Users\smith\Documents\GitHub\interbotix_ros_arms\interbotix_descriptions\urdf\rx150.urdf';                  % Path to .urdf model
        
        model;                      % Simualation model
        currentConfiguration;       % Current configuration of joints in robotic arm
        messages;                   % Description
        port;                       % Port number
        dxl_comm_result;            % Communication result
        dxl_getdata_result;         % GetParam result
        dxl_goal_position;          % Goal position
        dxl_error;                  % Dynamixel error
        dxl_led_value;              % Dynamixel LED value for write
    end
    
    % Member Constants
    properties (Access = private, Constant)
        ADDR_TORQUE_ENABLE = 64;                    % Dynamixel Control table address which controls torque (enable allows write to table)
        ADDR_LED_RED = 65;                          % Dynamixel Control table address enables/disables LED
        ADDR_GOAL_POSITION = 116;                   % Dynamixel Control table address sets goal position
        ADDR_PRESENT_POSITION = 132;                % Dynamixel Control table address that provides present position
        ADDR_RETURN_DELAY_TIME = 8;                 % Dynamixel Control table address that controls return delay time
        ADDR_PROFILE_ACCELERATION = 108;            % Dynamixel Control table address that controls max speed for servos
        ADDR_PROFILE_VELOCITY = 112;                % Dynamixel Control table address that controls max speed for servos
        
        LEN_PRO_LED_RED = 1;                        % Length of message in bytes when addressing LED control table location
        LEN_PRO_GOAL_POSITION = 4;                  % Length of message in bytes when addressing goal position control table location
        LEN_PRO_PRESENT_POSITION = 4;               % Length of message in bytes when addressing present position table location
        
        DXL_IDS = [0 1 2 3 4 5];                    % ID numbers of dynamixel servos
        
        PROTOCOL_VERSION = 2.0;                     % Protocol version that commands use to communicate with Dynamixel servos
        DXL_MINIMUM_POSITION_VALUE = 0;             % Minimum goal position value that servos will accept
        DXL_MAXIMUM_POSITION_VALUE = 4095;          % Maximum goal position value that servos accept
        DXL_MOVING_STATUS_THRESHOLD = 20;           % Dynamixel moving status threshold
        
        BAUD_RATE = 1000000;                        % Default data rate used during communication with servos (in bps)
        DEVICE_NAME = 'COM4';                       % Port name used during communication with Dynamixel servos
        COMM_SUCCESS = 0;                           % Communication Success result value
        COMM_TX_FAIL = -1001;                       % Communication Tx failed result value
        COMM_PORT_BUSY = -1000;                     % Communication port was busy
        
        TORQUE_ENABLE = 1;                          % Value for enabling the torque
        TORQUE_DISABLE = 0;                         % Value for disabling the torque
        RETURN_DELAY_TIME = 10;                     % Amount of time(2us) Dynamixel waits before replying
        MAX_VELOCITY = 2048;                        % Maximum velocity for servos
        ACCELERATION = 1024;                        % Acceleration constant for servos
        
        HOME = [0 0 0 0 0 0];                       % Home configuration of joint angles
        %PARK = [0 167 272.46 99 0 0];              % Parked configuration of joint angles
        PARK = [0 13 267.53 0 0 0];                      % Parked configuration of joint angles
        STATUS_OK = [0.00 1.00 0.00];               % Green color
        STATUS_FAIL = [1.00 0.00 0.00];             % Red color
        
        PRECISION = 5;                              % Digits of display precision for floating point numbers
        
        BASE_LINK = 'base_link';                    % Base link
        END_EFFECTOR = 'wrist_link';                % End effector
        
        PI = 3.1415926535897932384626433832795;     % pi in radians
        
        WEIGHTS = [0.001 0.001 0.001 0.01 0.01 0.01];  % weights are error in the pose matrix (x, y, z, a, b, c) not the output joint angles!
    end
    
    % Helper methods
    methods (Access = private)
        % Enables torque on a given servo
        function results = EnableTorque(app, id)
            if app.didOpenPort
                write1ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_TORQUE_ENABLE, app.TORQUE_ENABLE);
        
                app.dxl_comm_result = getLastTxRxResult(app.port, app.PROTOCOL_VERSION);
                app.dxl_error = getLastRxPacketError(app.port, app.PROTOCOL_VERSION);
                if app.dxl_comm_result ~= 0
                    results = false;
                    fprintf('%s %d\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result),app.dxl_comm_result);
                elseif app.dxl_error ~= 0
                    results = false;
                    fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
                else
                    results = true;
                    fprintf('Dynamixel %d has been successfully connected \n', id);
                end
                
                %results = app.dxl_comm_result & app.dxl_error;
            else
                results = false;
            end
        end
        
        % Disables torque on a given servo
        function results = DisableTorque(app, id)
            if app.didOpenPort
                write1ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_TORQUE_ENABLE, app.TORQUE_DISABLE);
        
                app.dxl_comm_result = getLastTxRxResult(app.port, app.PROTOCOL_VERSION);
                app.dxl_error = getLastRxPacketError(app.port, app.PROTOCOL_VERSION);
                if app.dxl_comm_result ~= 0
                    results = false;
                    fprintf('%s %d\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result),app.dxl_comm_result);
                elseif app.dxl_error ~= 0
                    results = false;
                    fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
                else
                    results = true;
                    fprintf('Dynamixel %d has been disconnected \n', id);
                end
                
                %results = app.dxl_comm_result & app.dxl_error;
            else
                results = false;
            end   
            
        end
        
        % Sets the acceleration of a servo in the robotic arm
        function results = SetAccelerationProfile(app, id)
            if app.didOpenPort
                write4ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_PROFILE_ACCELERATION, uint16(app.ACCELERATION));
                if getLastTxRxResult(app.port, app.PROTOCOL_VERSION) ~= 0
                    %printTxRxResult(app.PROTOCOL_VERSION, getLastTxRxResult(app.port, app.PROTOCOL_VERSION));
                    %printTxRxResult(uint(2), uint(getLastTxRxResult(0, 2)));
                elseif getLastRxPacketError(app.port, app.PROTOCOL_VERSION) ~= 0
                    %printRxPacketError(protocol, getLastRxPacketError(port, protocol));
                end
            end
        end
        
        % Sets the maximum velocity of a servo in the robotic arm
        function results = SetVelocityProfile(app, id)
            if app.didOpenPort
                write4ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_PROFILE_VELOCITY, uint16(app.MAX_VELOCITY));
                if getLastTxRxResult(app.port, app.PROTOCOL_VERSION) ~= 0
                    %printTxRxResult(app.PROTOCOL_VERSION, getLastTxRxResult(app.port, app.PROTOCOL_VERSION));
                    %printTxRxResult(uint(2), uint(getLastTxRxResult(0, 2)));
                elseif getLastRxPacketError(app.port, app.PROTOCOL_VERSION) ~= 0
                    %printRxPacketError(protocol, getLastRxPacketError(port, protocol));
                end
            end
        end
        
        % Sets the goal position (angle) of a servo in the robotic arm
        function results = SetGoalPosition(app, id, goal)
            if app.didOpenPort
                if  (app.DXL_MINIMUM_POSITION_VALUE < goal) && (goal < app.DXL_MAXIMUM_POSITION_VALUE)
                    write4ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_GOAL_POSITION, uint16(goal));
                    dxl_present_position = read4ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_PRESENT_POSITION);
                    if getLastTxRxResult(app.port, app.PROTOCOL_VERSION) ~= 0
                        %printTxRxResult(app.PROTOCOL_VERSION, getLastTxRxResult(app.port, app.PROTOCOL_VERSION));
                        %printTxRxResult(uint(2), uint(getLastTxRxResult(0, 2)));
                    elseif getLastRxPacketError(app.port, app.PROTOCOL_VERSION) ~= 0
                        %printRxPacketError(protocol, getLastRxPacketError(port, protocol));
                    end
                end
            end
        end
        
        % Sets the pose (group of angles) of the robotic arm in reverse order
        function results = SetPose(app, pose)
            if app.didOpenPort
                for i = fliplr(app.DXL_IDS)
                    goal = Deg2GoalPos(app,pose(i+1),i);
                    disp(i);
                    disp(goal);
                    SetGoalPosition(app,i,goal);
                end
                
                UpdateUI(app, pose)
            end
        end
        
        % Creates a configuration of joints from the default home configuration
        function results = SetConfiguration(app, arr)
            if app.didLoadModel
                config = app.model.homeConfiguration;
        
                for i = [1 2 3 4 5 6]
                    config(i).JointPosition = deg2rad(arr(i));
                end
                
                results = config;
            else
                results = struct('JointName',{},'JointPosition',{});
            end
        end
        
        % Iteratively solves the problem of which joint angles will produce a given pose 
        function results = IKSolver(app)
            if app.didLoadModel
                %initialguess = app.model.homeConfiguration;
                initialguess = app.currentConfiguration;
                transform = CalcPose(app);
                
                %disp(transform)
               
                ik = inverseKinematics;
                ik.RigidBodyTree = app.model;
                
                %disp(initialguess)
                %disp(app.WEIGHTS)
                [configSoln,solnInfo] = ik(app.END_EFFECTOR,transform,app.WEIGHTS,initialguess);
                %disp(rad2deg(configSoln(1).JointPosition));
                %disp(rad2deg(configSoln(2).JointPosition));
                %disp(rad2deg(configSoln(3).JointPosition));
            
                results = configSoln;
            else
                results = struct('JointName',{},'JointPosition',{});
            end
        end
        
        % Converts a solution from the simulation into joint positions (angles) in the frame of the robotic arm
        function results = ConvertSolnToPose(app, config)
            results = zeros(1,6);
                
            results(1) = rad2deg(config(1).JointPosition);
            results(2) = rad2deg(config(2).JointPosition);
            results(3) = rad2deg(config(3).JointPosition);
            results(4) = rad2deg(config(4).JointPosition);
            results(5) = rad2deg(config(5).JointPosition);
            results(6) = rad2deg(config(6).JointPosition);
        end
        
        % Converts a given angle in degrees to a goal position
        function results = Deg2GoalPos(app, deg, id)
            if id > 0
                a = -1;
            else
                a = 1;
            end
            
            if deg >= 180
                deg = deg - 360;
            end
            
            results = a * (512/45)* deg + 2048;
        end
        
        % Loads Dynamixel libraries
        function results = LoadDynamixelLibs(app)
            clc;
            clear all;
            
            % Get library name
            if strcmp(computer, 'PCWIN')
              app.lib_name = 'dxl_x86_c';
            elseif strcmp(computer, 'PCWIN64')
              app.lib_name = 'dxl_x64_c';
            elseif strcmp(computer, 'GLNX86')
              app.lib_name = 'libdxl_x86_c';
            elseif strcmp(computer, 'GLNXA64')
              app.lib_name = 'libdxl_x64_c';
            elseif strcmp(computer, 'MACI64')
              app.lib_name = 'libdxl_mac_c';
            end
            
            % Load Libraries
            if ~libisloaded(app.lib_name)
                addpath(fullfile("C:/","Users","smith","Documents","GitHub","DynamixelSDK","matlab"));
                [notfound, warnings] = loadlibrary(app.lib_name, 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h', 'addheader', 'group_bulk_read.h', 'addheader', 'group_bulk_write.h');
                
                %if ~isempty(notfound{1}) & ~isempty(notfound{2}) & ~isempty(notfound{3}) & ~isempty(notfound{4})
                if numel(notfound) == 0
                   results = true;
                else
                    results = false;
                end
            else
                results = true;
            end
        end
        
        % Load 3D model from .urdf file
        function results = LoadModel(app)
            if ismac
                app.urdf_path = "/Users/smith/Documents/GitHub/interbotix_ros_arms/interbotix_descriptions/urdf/rx150.urdf";
                app.model = importrobot(app.urdf_path);
                results = true;
            elseif isunix
                app.urdf_path = "/Users/smith/Documents/GitHub/interbotix_ros_arms/interbotix_descriptions/urdf/rx150.urdf";
                app.model = importrobot(app.urdf_path);
                results = true;
            elseif ispc
                app.urdf_path = "C:\Users\smith\Documents\GitHub\interbotix_ros_arms\interbotix_descriptions\urdf\rx150.urdf";
                app.model = importrobot(app.urdf_path);
                results = true;
            else
                disp('Platform not supported')
                results = false;
            end
        end
        
        % Unload Library
        function results = UnloadDynamixelLibs(app)
            if app.didLoadLib
                app.didLoadLib = false;
                unloadlibrary(app.lib_name);
            end
        end
        
        % Enable torque on all servos
        function results = EnableAllTorques(app)
            for i = app.DXL_IDS
                EnableTorque(app, i);
            end
        end
        
        % Enable torque on all servos
        function results = SetMovementProfile(app)
            for i = app.DXL_IDS
                SetAccelerationProfile(app, i);
                SetVelocityProfile(app, i);
            end
        end
        
        % Disable torque on all servos
        function results = DisableAllTorques(app)
            for i = app.DXL_IDS
                 DisableTorque(app, i);
            end
        end
        
        % Initializes member variables and opens communication with
        % dynamixel servos
        function results = Init(app)
            % Initialize PortHandler Structs
            % Set the port number
            app.port = portHandler(app.DEVICE_NAME);
            
            % Initialize PacketHandler Structs
            packetHandler();
            
            app.dxl_comm_result = app.COMM_TX_FAIL;                                                         % Communication result
            app.dxl_goal_position = [app.DXL_MINIMUM_POSITION_VALUE app.DXL_MAXIMUM_POSITION_VALUE];        % Goal position 
            app.dxl_error = 0;                                                                              % Dynamixel error
            app.dxl_led_value = [0 255];                                                                    % Dynamixel LED value for write
            
            app.didOpenPort = OpenCommPort(app);
            
            app.didSetBaudRate = SetPortBaudRate(app);
            
            % Enable all servos
            EnableAllTorques(app);
            
            % Set how quickly the servos will move towards a given goal
            SetMovementProfile(app);
            
            % Set the pose of the robotic arm to the default home position
            SetPose(app,app.HOME);
            
            % Load .urdf model into memory
            app.didLoadModel = LoadModel(app);
            app.currentConfiguration = SetConfiguration(app,app.HOME);
            
            results = app.didOpenPort & app.didSetBaudRate & app.didLoadModel;
        end
        
        % Open port
        function results = OpenCommPort(app)
            if app.didLoadLib
                if (openPort(app.port))
                    app.ConnectionLamp.Color = app.STATUS_OK;
                    fprintf('Succeeded to open port %d!\n',app.port);
                    results = true;
                else
                    UnloadDynamixelLibs(app);
                    app.ConnectionLamp.Color = app.STATUS_FAIL;
                    fprintf('Failed to open the port!\n');
                    results = false;
                end
            else
                results = false;
            end
        end
        
        % Set port baudrate
        function results = SetPortBaudRate(app)
            if app.didOpenPort
                if (setBaudRate(app.port, app.BAUD_RATE))
                    fprintf('Succeeded to change the baudrate!\n');
                    results = true;
                else
                    UnloadDynamixelLibs(app);
                    fprintf('Failed to change the baudrate!\n');
                    results = false;
                end
            else
                results = false;
            end
        end
        
        % Close port
        function results = CloseCommPort(app)
            if app.didOpenPort
                closePort(app.port);
                results = false;
                disp("Closed port");
            else
                results = app.didOpenPort;
            end
        end
        
        
        function pose = CalcPose(app)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;
            a = app.alphaEditField.Value;
            b = app.betaEditField.Value;
            c = app.gammaEditField.Value;
            
            pose = CalcRotMatrix(app,a,b,c,x,y,z);
        end
        
        % Calcuates a rotation matrix from three rotational parameters and x, y, z
        % translation
        function rot = CalcRotMatrix(app,a,b,c,x,y,z)
            T = [1 0 0 x; 0 1 0 y;0 0 1 z;0 0 0 1];
            Rx = [1 0 0 0;0 cos(a) sin(a) 0;0 sin(a) -cos(a) 0; 0 0 0 1];
            Ry = [cos(b) 0 sin(b) 0;0 1 0 0;sin(b) 0 -cos(b) 0;0 0 0 1];
            Rz = [cos(c) sin(c) 0 0;sin(c) -cos(c) 0 0;0 0 1 0;0 0 0 1];
            rot = Rx * Ry * Rz * T;
        end
        
        % Updates the user interface
        function results = UpdateUI(app, pose)
            % Display results
            app.WaistServoKnob.Value = limit(app,pose(1),app.WaistServoKnob.Limits(2),app.WaistServoKnob.Limits(1));
            app.WaistServoKnob.Tooltip = mat2str(pose(1),app.PRECISION);
            
            app.ShoulderServoKnob.Value = limit(app,pose(2),app.ShoulderServoKnob.Limits(2),app.ShoulderServoKnob.Limits(1));
            app.ShoulderServoKnob.Tooltip = mat2str(pose(2),app.PRECISION);
            
            app.ElbowServoKnob.Value = limit(app,pose(3),app.ElbowServoKnob.Limits(2),app.ElbowServoKnob.Limits(1));
            app.ElbowServoKnob.Tooltip = mat2str(pose(3),app.PRECISION);
            
            app.WristAngleServoKnob.Value = limit(app,pose(4),app.WristAngleServoKnob.Limits(2),app.WristAngleServoKnob.Limits(1));
            app.WristAngleServoKnob.Tooltip = mat2str(pose(4),app.PRECISION);
            
            app.WristRotateServoKnob.Value = limit(app,pose(5),app.WristRotateServoKnob.Limits(2),app.WristRotateServoKnob.Limits(1));
            app.WristRotateServoKnob.Tooltip = mat2str(pose(5),app.PRECISION);
            
            app.GripperServoKnob.Value = limit(app,pose(6),app.GripperServoKnob.Limits(2),app.GripperServoKnob.Limits(1));
            app.GripperServoKnob.Tooltip = mat2str(pose(6),app.PRECISION);
            
            app.StatusTextArea.Value = mat2str(pose,app.PRECISION);
        end
        
        % Limits a value to the input range
        function results = limit(app,value,upper,lower)
            results = min(max(value,lower),upper);    
        end
        
        function results = UpdateKnobs(app)
            pose = [app.WaistServoKnob.Value app.ShoulderServoKnob.Value app.ElbowServoKnob.Value app.WristAngleServoKnob.Value app.WristRotateServoKnob.Value app.GripperServoKnob.Value];
            SetPose(app,pose);
            UpdateUI(app, pose);
            app.currentConfiguration = SetConfiguration(app,pose);  
        end
        
        % Moves the arm into a safely parked configuration
        function results = Park(app)
            SetPose(app,app.HOME);
            pause(1);
            SetPose(app,app.PARK);
            pause(5);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.didLoadLib = LoadDynamixelLibs(app);
            app.alphaEditFieldLabel.Text = char(945);
            app.betaEditFieldLabel.Text = char(946);
            app.gammaEditFieldLabel.Text = char(947);
            Init(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            beep;
            acceptExit = uiconfirm(app.UIFigure,'Do you wish to exit?','Confirmation');
        
            if acceptExit == "OK"
                Park(app);
                DisableAllTorques(app);
                app.didOpenPort = CloseCommPort(app);
                UnloadDynamixelLibs(app);
                
                % Delete app
                delete(app);
            end
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            soln = IKSolver(app);
            
            % Convert the inverse kinematic solution into a set of angles suitable for
            % the robotic arm
            % Final pose is the solution reached by IK
            final_pose = ConvertSolnToPose(app,soln);
            
            subplot(1,3,2);show(app.model,soln);
            
            acceptSolution = uiconfirm(app.UIFigure,'Accept IK solution?','Confirmation',...
           'Options',{'Yes','No'});
        
            if acceptSolution == "Yes"
                SetPose(app, final_pose);
                UpdateUI(app, final_pose);
                app.currentConfiguration = soln;
            end
        end

        % Button pushed function: DisplayModelButton
        function DisplayModelButtonPushed(app, event)
            show(app.model,app.currentConfiguration);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % Set the pose of the robotic arm to the default home position
            SetPose(app,app.HOME);
            UpdateUI(app, app.HOME);
            app.currentConfiguration = SetConfiguration(app,app.HOME);
        end

        % Value changed function: WaistServoKnob
        function WaistServoKnobValueChanged(app, event)
            UpdateKnobs(app);  
        end

        % Value changed function: ShoulderServoKnob
        function ShoulderServoKnobValueChanged(app, event)
            UpdateKnobs(app);
        end

        % Value changed function: ElbowServoKnob
        function ElbowServoKnobValueChanged(app, event)
            UpdateKnobs(app);
        end

        % Value changed function: WristAngleServoKnob
        function WristAngleServoKnobValueChanged(app, event)
            UpdateKnobs(app);
        end

        % Value changed function: WristRotateServoKnob
        function WristRotateServoKnobValueChanged(app, event)
           UpdateKnobs(app);
        end

        % Value changed function: GripperServoKnob
        function GripperServoKnobValueChanged(app, event)
            UpdateKnobs(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {586, 586, 586};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {586, 586};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 224};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WaistServo1Label_2 = uilabel(app.LeftPanel);
            app.WaistServo1Label_2.Text = 'Waist (Servo #1)';
            app.WaistServoKnob = uiknob(app.LeftPanel, 'continuous');
            app.WaistServoKnob.Limits = [-180 180];
            app.WaistServoKnob.ValueChangedFcn = createCallbackFcn(app, @WaistServoKnobValueChanged, true);
            app.ShoulderServo2Label = uilabel(app.LeftPanel);
            app.ShoulderServo2Label.Text = 'Shoulder (Servo #2)';
            app.ShoulderServoKnob = uiknob(app.LeftPanel, 'continuous');
            app.ShoulderServoKnob.Limits = [-90 90];
            app.ShoulderServoKnob.ValueChangedFcn = createCallbackFcn(app, @ShoulderServoKnobValueChanged, true);
            app.ElbowServo3Label = uilabel(app.LeftPanel);
            app.ElbowServo3Label.Text = 'Elbow (Servo #3)';
            app.ElbowServoKnob = uiknob(app.LeftPanel, 'continuous');
            app.ElbowServoKnob.Limits = [-90 90];
            app.ElbowServoKnob.ValueChangedFcn = createCallbackFcn(app, @ElbowServoKnobValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'XYZ Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StatusTextAreaLabel = uilabel(app.CenterPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.CenterPanel);
            app.MoveButton = uibutton(app.CenterPanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.XEditFieldLabel = uilabel(app.CenterPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CenterPanel, 'numeric');
            app.XEditField.Value = -0.15;
            app.YEditFieldLabel = uilabel(app.CenterPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.CenterPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ZEditField.Value = 0.3039;
            app.alphaEditFieldLabel = uilabel(app.CenterPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.CenterPanel, 'numeric');
            app.betaEditFieldLabel = uilabel(app.CenterPanel);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.CenterPanel, 'numeric');
            app.gammaEditFieldLabel = uilabel(app.CenterPanel);
            app.gammaEditFieldLabel.Text = {'gamma'; ''};
            app.gammaEditField = uieditfield(app.CenterPanel, 'numeric');
            app.DisplayModelButton = uibutton(app.CenterPanel, 'push');
            app.DisplayModelButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayModelButtonPushed, true);
            app.DisplayModelButton.Text = 'Display Model';
            app.HomeButton = uibutton(app.CenterPanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ConnectionLampLabel = uilabel(app.CenterPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.CenterPanel);
            app.ConnectionLamp.Tooltip = {'Connection State (OPEN/CLOSED)'};
            app.RightPanel = uipanel(app.GridLayout);
            app.WristAngleServo4Label = uilabel(app.RightPanel);
            app.WristAngleServo4Label.Text = 'Wrist Angle (Servo #4)';
            app.WristAngleServoKnob = uiknob(app.RightPanel, 'continuous');
            app.WristAngleServoKnob.Limits = [-90 90];
            app.WristAngleServoKnob.ValueChangedFcn = createCallbackFcn(app, @WristAngleServoKnobValueChanged, true);
            app.WristRotateServo5Label = uilabel(app.RightPanel);
            app.WristRotateServo5Label.Text = 'Wrist Rotate (Servo #5)';
            app.WristRotateServoKnob = uiknob(app.RightPanel, 'continuous');
            app.WristRotateServoKnob.Limits = [-180 180];
            app.WristRotateServoKnob.ValueChangedFcn = createCallbackFcn(app, @WristRotateServoKnobValueChanged, true);
            app.GripperServo6Label = uilabel(app.RightPanel);
            app.GripperServo6Label.Text = 'Gripper (Servo #6)';
            app.GripperServoKnob = uiknob(app.RightPanel, 'continuous');
            app.GripperServoKnob.Limits = [-180 180];
            app.GripperServoKnob.ValueChangedFcn = createCallbackFcn(app, @GripperServoKnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RX150

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end